classdef DiagCaja < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DiagCajaUIFigure               matlab.ui.Figure
        DiagramadecajaconbigotesLabel  matlab.ui.control.Label
        CamposButtonGroup              matlab.ui.container.ButtonGroup
        CursosButton                   matlab.ui.control.RadioButton
        SemestreButton                 matlab.ui.control.RadioButton
        PesoButton                     matlab.ui.control.RadioButton
        AlturaButton                   matlab.ui.control.RadioButton
        EdadButton                     matlab.ui.control.RadioButton
        UITable                        matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             T = readtable('info_students.csv');
            app.UITable.Data = T;
            
        end

        % Selection changed function: CamposButtonGroup
        function CamposButtonGroupSelectionChanged(app, event)
             T = readtable('info_students.csv');
            v1 = app.EdadButton.Value;
            v2 = app.AlturaButton.Value;
            v3 = app.PesoButton.Value;
            v4 = app.SemestreButton.Value;
            v5 = app.CursosButton.Value;
            if  v1 == 1
                R=T.age;       
                boxplot(app.UIAxes,R);
                app.UIAxes.XLabel.String = 'Todos los alumnos';
                app.UIAxes.YLabel.String = 'Edades';
                app.UIAxes.Title.String = 'Edad de todos los Estudiantes';
            elseif v2 == 1
                R=T.height;
                boxplot(app.UIAxes,R);
                app.UIAxes.XLabel.String = 'Todos los alumnos';
                app.UIAxes.YLabel.String = 'Alturas';
                app.UIAxes.Title.String = 'Altura de todos los Estudiantes';
            elseif v3 == 1
                R=T.weight;
                boxplot(app.UIAxes,R);
                app.UIAxes.XLabel.String = 'Todos los alumnos';
                app.UIAxes.YLabel.String = 'Pesos';
                app.UIAxes.Title.String = 'Peso de todos los Estudiantes';
            elseif v4 == 1
                R=T.semester
                boxplot(app.UIAxes,R);
                app.UIAxes.XLabel.String = 'Todos los alumnos';
                app.UIAxes.YLabel.String = 'Semestres Cursados';
                app.UIAxes.Title.String = 'Semestres cursados de todos los Estudiantes';
            elseif v5 == 1
                R=T.courses_taken;
                boxplot(app.UIAxes,R);
                app.UIAxes.XLabel.String = 'Todos los alumnos';
                app.UIAxes.YLabel.String = 'Cursos Tomados';
                app.UIAxes.Title.String = 'Cursos Tomados de todos los Estudiantes';
            else
                app.UIAxes.cla;
            end
            
        end

        % Close request function: DiagCajaUIFigure
        function DiagCajaUIFigureCloseRequest(app, event)
            delete(app)
            Menu
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DiagCajaUIFigure = uifigure('Visible', 'off');
            app.DiagCajaUIFigure.Name = 'DiagCaja';
            app.DiagCajaUIFigure.CloseRequestFcn = createCallbackFcn(app, @DiagCajaUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.DiagCajaUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.DiagCajaUIFigure);
            app.UITable.ColumnName = {'Nombre'; 'Edad'; 'Sexo'; 'Altura'; 'Peso'; 'Semestre'; 'Cursos'; 'Mascotas'; 'C.Origen'; 'Video Jugador'};
            app.UITable.RowName = {};
            app.CamposButtonGroup = uibuttongroup(app.DiagCajaUIFigure);
            app.CamposButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CamposButtonGroupSelectionChanged, true);
            app.CamposButtonGroup.Title = 'Campos';
            app.EdadButton = uiradiobutton(app.CamposButtonGroup);
            app.EdadButton.Text = 'Edad';
            app.EdadButton.Value = true;
            app.AlturaButton = uiradiobutton(app.CamposButtonGroup);
            app.AlturaButton.Text = 'Altura';
            app.PesoButton = uiradiobutton(app.CamposButtonGroup);
            app.PesoButton.Text = 'Peso';
            app.SemestreButton = uiradiobutton(app.CamposButtonGroup);
            app.SemestreButton.Text = 'Semestre';
            app.CursosButton = uiradiobutton(app.CamposButtonGroup);
            app.CursosButton.Text = 'Cursos';
            app.DiagramadecajaconbigotesLabel = uilabel(app.DiagCajaUIFigure);
            app.DiagramadecajaconbigotesLabel.Text = 'Diagrama de caja con bigotes';
            % Show the figure after all components are created
            app.DiagCajaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiagCaja

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DiagCajaUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DiagCajaUIFigure)
        end
    end
end