classdef Moda < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ModaUIFigure                 matlab.ui.Figure
        DesviacionestandarEditField  matlab.ui.control.NumericEditField
        DesviacionestandarLabel      matlab.ui.control.Label
        VarianzaEditField            matlab.ui.control.NumericEditField
        VarianzaEditFieldLabel       matlab.ui.control.Label
        MinEditField                 matlab.ui.control.NumericEditField
        MinEditFieldLabel            matlab.ui.control.Label
        MaxEditField                 matlab.ui.control.NumericEditField
        MaxEditFieldLabel            matlab.ui.control.Label
        ValorMedioMedianayModaLabel  matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        CamposButtonGroup            matlab.ui.container.ButtonGroup
        CursosButton                 matlab.ui.control.RadioButton
        SemestreButton               matlab.ui.control.RadioButton
        PesoButton                   matlab.ui.control.RadioButton
        AlturaButton                 matlab.ui.control.RadioButton
        EdadButton                   matlab.ui.control.RadioButton
        ModaEditField                matlab.ui.control.NumericEditField
        ModaEditFieldLabel           matlab.ui.control.Label
        MedianaEditField             matlab.ui.control.NumericEditField
        MedianaEditFieldLabel        matlab.ui.control.Label
        ValormedioEditField          matlab.ui.control.NumericEditField
        ValormedioEditFieldLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            T = readtable('info_students.csv');
            app.UITable.Data = T;
        end

        % Selection changed function: CamposButtonGroup
        function CamposButtonGroupSelectionChanged(app, event)
              T = readtable('info_students.csv');
            v1 = app.EdadButton.Value;
            v2 = app.AlturaButton.Value;
            v3 = app.PesoButton.Value;
            v4 = app.SemestreButton.Value;
            v5 = app.CursosButton.Value;
            if  v1 == 1
                R=T.age;
                sort(R);
                Medio = mean(R);
                Mediaa = median(R);
                Moda = mode(R);
                Mini = min(R);
                Maxi = max(R);
                Vari = var(R,1);
                Desvi = std(R);
                app.ValormedioEditField.Value = Medio;
                app.MedianaEditField.Value = Mediaa;
                app.ModaEditField.Value = Moda; 
                app.MinEditField.Value = Mini;
                app.MaxEditField.Value = Maxi;
                app.VarianzaEditField.Value = Vari;
                app.DesviacionestandarEditField.Value = Desvi;
            elseif v2 == 1
                R = T.height;
                sort(R); 
                Medio = mean(R);
                Mediaa = median(R);
                Moda = mode(R);
                Mini = min(R);
                Maxi = max(R);
                Vari = var(R,1);
                Desvi = std(R);
                app.ValormedioEditField.Value = Medio;
                app.MedianaEditField.Value = Mediaa;
                app.ModaEditField.Value = Moda;
                app.MinEditField.Value = Mini;
                app.MaxEditField.Value = Maxi;
                app.VarianzaEditField.Value = Vari;
                app.DesviacionestandarEditField.Value = Desvi;
            elseif v3 == 1
                R = T.weight;
                sort(R); 
                Medio = mean(R);
                Mediaa = median(R);
                Moda = mode(R);
                Mini = min(R);
                Maxi = max(R);
                Vari = var(R,1);
                Desvi = std(R);
                app.ValormedioEditField.Value = Medio;
                app.MedianaEditField.Value = Mediaa;
                app.ModaEditField.Value = Moda;
                app.MinEditField.Value = Mini;
                app.MaxEditField.Value = Maxi;
                app.VarianzaEditField.Value = Vari;
                app.DesviacionestandarEditField.Value = Desvi;
            elseif v4 == 1
                R = T.semester;
                sort(R); 
                Medio = mean(R);
                Mediaa = median(R);
                Moda = mode(R);
                Mini = min(R);
                Maxi = max(R);
                Vari = var(R,1);
                Desvi = std(R);
                app.ValormedioEditField.Value = Medio;
                app.MedianaEditField.Value = Mediaa;
                app.ModaEditField.Value = Moda;
                app.MinEditField.Value = Mini;
                app.MaxEditField.Value = Maxi;
                app.VarianzaEditField.Value = Vari;
                app.DesviacionestandarEditField.Value = Desvi;
            elseif v5 == 1
                R = T.courses_taken;
                sort(R); 
                Medio = mean(R);
                Mediaa = median(R);
                Moda = mode(R);
                Mini = min(R);
                Maxi = max(R);
                Vari = var(R,1);
                Desvi = std(R);
                app.ValormedioEditField.Value = Medio;
                app.MedianaEditField.Value = Mediaa;
                app.ModaEditField.Value = Moda;
                app.MinEditField.Value = Mini;
                app.MaxEditField.Value = Maxi;
                app.VarianzaEditField.Value = Vari;
                app.DesviacionestandarEditField.Value = Desvi;
            else
                app.UIAxes.cla;
            end
            
        end

        % Close request function: ModaUIFigure
        function ModaUIFigureCloseRequest(app, event)
            delete(app)
            Menu
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ModaUIFigure = uifigure('Visible', 'off');
            app.ModaUIFigure.Name = 'Moda';
            app.ModaUIFigure.CloseRequestFcn = createCallbackFcn(app, @ModaUIFigureCloseRequest, true);
            app.ValormedioEditFieldLabel = uilabel(app.ModaUIFigure);
            app.ValormedioEditFieldLabel.Text = 'Valor medio';
            app.ValormedioEditField = uieditfield(app.ModaUIFigure, 'numeric');
            app.MedianaEditFieldLabel = uilabel(app.ModaUIFigure);
            app.MedianaEditFieldLabel.Text = 'Mediana';
            app.MedianaEditField = uieditfield(app.ModaUIFigure, 'numeric');
            app.ModaEditFieldLabel = uilabel(app.ModaUIFigure);
            app.ModaEditFieldLabel.Text = 'Moda';
            app.ModaEditField = uieditfield(app.ModaUIFigure, 'numeric');
            app.CamposButtonGroup = uibuttongroup(app.ModaUIFigure);
            app.CamposButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CamposButtonGroupSelectionChanged, true);
            app.CamposButtonGroup.Title = 'Campos';
            app.EdadButton = uiradiobutton(app.CamposButtonGroup);
            app.EdadButton.Text = 'Edad';
            app.EdadButton.Value = true;
            app.AlturaButton = uiradiobutton(app.CamposButtonGroup);
            app.AlturaButton.Text = 'Altura';
            app.PesoButton = uiradiobutton(app.CamposButtonGroup);
            app.PesoButton.Text = 'Peso';
            app.SemestreButton = uiradiobutton(app.CamposButtonGroup);
            app.SemestreButton.Text = 'Semestre';
            app.CursosButton = uiradiobutton(app.CamposButtonGroup);
            app.CursosButton.Text = 'Cursos';
            app.UITable = uitable(app.ModaUIFigure);
            app.UITable.ColumnName = {'Nombre'; 'Edad'; 'Sexo'; 'Altura'; 'Peso'; 'Semestre'; 'Cursos'; 'Mascotas'; 'C.Origen'; 'Video Jugador'};
            app.UITable.RowName = {};
            app.ValorMedioMedianayModaLabel = uilabel(app.ModaUIFigure);
            app.ValorMedioMedianayModaLabel.Text = 'Valor Medio, Mediana y Moda';
            app.MaxEditFieldLabel = uilabel(app.ModaUIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.ModaUIFigure, 'numeric');
            app.MinEditFieldLabel = uilabel(app.ModaUIFigure);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.ModaUIFigure, 'numeric');
            app.VarianzaEditFieldLabel = uilabel(app.ModaUIFigure);
            app.VarianzaEditFieldLabel.Text = 'Varianza';
            app.VarianzaEditField = uieditfield(app.ModaUIFigure, 'numeric');
            app.DesviacionestandarLabel = uilabel(app.ModaUIFigure);
            app.DesviacionestandarLabel.Text = {'Desviacion '; 'estandar'};
            app.DesviacionestandarEditField = uieditfield(app.ModaUIFigure, 'numeric');
            % Show the figure after all components are created
            app.ModaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Moda

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ModaUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ModaUIFigure)
        end
    end
end