classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Distribucion       matlab.ui.Figure
        FrecuenciaabsolutayenporcentajeLabel  matlab.ui.control.Label
        CamposButtonGroup  matlab.ui.container.ButtonGroup
        CursosButton       matlab.ui.control.RadioButton
        SemestreButton     matlab.ui.control.RadioButton
        PesoButton         matlab.ui.control.RadioButton
        AlturaButton       matlab.ui.control.RadioButton
        EdadButton         matlab.ui.control.RadioButton
        UITable2           matlab.ui.control.Table
        UITable            matlab.ui.control.Table
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             T = readtable('info_students.csv');
            app.UITable.Data = T;
            R=tabulate(T.age)
            app.UITable2.Data = R;
            plot(app.UIAxes,R);
        end

        % Callback function
        function DistribucionButtonPushed(app, event)
 
            
        end

        % Close request function: Distribucion
        function DistribucionCloseRequest(app, event)
            delete(app)
            Menu
            
        end

        % Selection changed function: CamposButtonGroup
        function CamposButtonGroupSelectionChanged(app, event)
           
            T = readtable('info_students.csv');
            v1 = app.EdadButton.Value;
            v2 = app.AlturaButton.Value;
            v3 = app.PesoButton.Value;
            v4 = app.SemestreButton.Value;
            v5 = app.CursosButton.Value;
            if  v1 == 1
                R=tabulate(T.age);
                app.UITable2.Data = R;
                plot(app.UIAxes,R);
            elseif v2 == 1
                R=tabulate(T.height);
                app.UITable2.Data = R;
                plot(app.UIAxes,R);
            elseif v3 == 1
                R=tabulate(T.weight);
                app.UITable2.Data = R;
                plot(app.UIAxes,R);
            elseif v4 == 1
                R=tabulate(T.semester);
                app.UITable2.Data = R;
                plot(app.UIAxes,R);
            elseif v5 == 1
                R=tabulate(T.courses_taken);
                app.UITable2.Data = R;
                plot(app.UIAxes,R);
            else
                app.UIAxes.cla;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Distribucion = uifigure('Visible', 'off');
            app.Distribucion.Name = 'Distribucion';
            app.Distribucion.CloseRequestFcn = createCallbackFcn(app, @DistribucionCloseRequest, true);
            app.UIAxes = uiaxes(app.Distribucion);
            title(app.UIAxes, 'Distribucion')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.Distribucion);
            app.UITable.ColumnName = {'Nombre'; 'Edad'; 'Sexo'; 'Altura'; 'Peso'; 'Semestre'; 'Cursos'; 'Mascotas'; 'C.Origen'; 'Video Jugador'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.Distribucion);
            app.UITable2.ColumnName = {'Valor'; 'Freq. Absoluta'; 'Freq. Porcentaje'};
            app.UITable2.RowName = {};
            app.CamposButtonGroup = uibuttongroup(app.Distribucion);
            app.CamposButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CamposButtonGroupSelectionChanged, true);
            app.CamposButtonGroup.Title = 'Campos';
            app.EdadButton = uiradiobutton(app.CamposButtonGroup);
            app.EdadButton.Text = 'Edad';
            app.EdadButton.Value = true;
            app.AlturaButton = uiradiobutton(app.CamposButtonGroup);
            app.AlturaButton.Text = 'Altura';
            app.PesoButton = uiradiobutton(app.CamposButtonGroup);
            app.PesoButton.Text = 'Peso';
            app.SemestreButton = uiradiobutton(app.CamposButtonGroup);
            app.SemestreButton.Text = 'Semestre';
            app.CursosButton = uiradiobutton(app.CamposButtonGroup);
            app.CursosButton.Text = 'Cursos';
            app.FrecuenciaabsolutayenporcentajeLabel = uilabel(app.Distribucion);
            app.FrecuenciaabsolutayenporcentajeLabel.Text = 'Frecuencia absoluta y en porcentaje';
            % Show the figure after all components are created
            app.Distribucion.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Distribucion)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Distribucion)
        end
    end
end